classdef DOAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KQEditField                 matlab.ui.control.EditField
        KtquEditFieldLabel          matlab.ui.control.Label
        GioiHanEditField            matlab.ui.control.EditField
        GIihnEditFieldLabel         matlab.ui.control.Label
        HamEditField                matlab.ui.control.EditField
        HmvdliuEditFieldLabel       matlab.ui.control.Label
        ChoiseDropDown              matlab.ui.control.DropDown
        PhngphpxlDropDownLabel      matlab.ui.control.Label
        BT6_7Button                 matlab.ui.control.StateButton
        BT5_3Button                 matlab.ui.control.StateButton
        BT4_3Button                 matlab.ui.control.StateButton
        BT3_2Button                 matlab.ui.control.StateButton
        RUNButton                   matlab.ui.control.Button
        MSSV20200058Label           matlab.ui.control.Label
        Lp20DTV1Label               matlab.ui.control.Label
        HvtnNguynNgcNguynLabel      matlab.ui.control.Label
        MnhcPhngphptnhvMatlabLabel  matlab.ui.control.Label
        titleLabel                  matlab.ui.control.Label
        Image_avt                   matlab.ui.control.Image
        Image_logo                  matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BT3_2Button
        function BT3_2ButtonValueChanged(app, event)
            value1 = app.BT3_2Button.Value;
            if (value1 == 1)
            % khi nut bai tap 3.2 duoc nhan == 1    
                dd = app.ChoiseDropDown;
                dd.Items = {'Lặp','Dây cung','Tiếp tuyến'};
                % tao cac lua chon cho dropdown
                fx = num2str('x^4 - 3*x + 1 = 0');
                app.HamEditField.Value = fx;
                % hien thi ham len o ham
                e = num2str('[0;1]');
                app.GioiHanEditField.Value = e;
                % hien thi chuoi 
            end
        end

        % Value changed function: BT4_3Button
        function BT4_3ButtonValueChanged(app, event)
            value2 = app.BT4_3Button.Value;
            if (value2 == 1)
                dd = app.ChoiseDropDown;
                dd.Items = {'Cholesky(b1)','Cholesky(b2)'};
                % tao cac lua chon cho dropdown
                array = num2str('A = [5 3 2 1;3 6 1 2;2 1 5 1;1 2 1 6] ');
                app.HamEditField.Value = array;
                % hien thi mang A
                e = num2str('B  = [1;4;7;2]');
                app.GioiHanEditField.Value = e;
                % hien thi mang B
            end
        end

        % Value changed function: BT5_3Button
        function BT5_3ButtonValueChanged(app, event)
            value3 = app.BT5_3Button.Value;
            if (value3 == 1)
                dd = app.ChoiseDropDown;
                dd.Items = {'Langrange'};
                % tao cac lua chon cho dropdown
                array = num2str('x = [321 322.8 324.2 325] y = [2.50651 2.50893 2.51081 2.51188]');
                app.HamEditField.Value = array;
                e = num2str('x0 = 323.5');
                app.GioiHanEditField.Value = e;
                % hien thi gia tri can tinh
            end
        end

        % Value changed function: BT6_7Button
        function BT6_7ButtonValueChanged(app, event)
            value4 = app.BT6_7Button.Value;
            if (value4 == 1)
                dd = app.ChoiseDropDown;
                dd.Items = {'Euler','Euler cải tiến','R-K4'};
                % tao cac lua chon cho dropdown
                fxy = num2str('y`= x^2 + y^2');
                app.HamEditField.Value = fxy;
                % hien thi ham
                v = num2str('[0; 0.5]');
                app.GioiHanEditField.Value = v;
                % hien thi gioi han tinh toan
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            value1 = app.BT3_2Button.Value;
            % value1 kiem tra xem nut bai tap 3.2 co duoc nhan hay khong
            value2 = app.BT4_3Button.Value;
            % value2 kiem tra xem nut bai tap 4.3 co duoc nhan hay khong
            value3 = app.BT5_3Button.Value;
            % value3 kiem tra xem nut bai tap 5.3 co duoc nhan hay khong
            value4 = app.BT6_7Button.Value;
            % value4 kiem tra xem nut bai tap 6.7 co duoc nhan hay khong
% BT3_2 (bai8)
            if value1 == 1
                %ham phuong phap lap
                fx = @(x)  (x^4 + 1)/3;
                %ham phuong phap day cung va tiep tuyen
                f = @(x) x^4 - 3*x + 1 ;
                a = 0;
                b = 1;
                saiso = 0.01;
                switch app.ChoiseDropDown.Value
                    % phuong phap lap
                    case 'Lặp'
                        for i = 1:1:500
                            b = fx(a);
                            if abs(b-a)< saiso
                                break;
                            end
                            a = b;
                        end
                        b = num2str(b);
                        % chuyen doi tu so sang chuoi
                        app.KQEditField.Value = b;
                        % hien thi len o ket qua
                    % phuong phap day cung
                    case 'Dây cung'
                        for i = 1:1:500
                            x1 = (a*f(b) -b*f(a))/(f(b) -f(a));
                            if f(x1)*f(a) < 0
                                a = x1;
                            else
                                b = x1;
                            end
                            e = b -a;
                            if e < saiso
                                break;
                            end
                        end
                        x1 = num2str(x1);
                        % chuyen doi tu so sang chuoi
                        app.KQEditField.Value = x1;
                        % hien thi len o ket qua
                    %phuong phap tiep tuyen
                    case 'Tiếp tuyến'
                        syms x;
                        fd1 = str2func(['@(x)' char(diff(f(x)))]);
                        for i = 1:1:500
                            x1 =  a -f(a)/fd1(a);
                            if abs(x1-a)<saiso
                                break;
                            end
                            a = x1;
                        end
                        x1 = num2str(x1);
                        % chuyen doi tu so sang chuoi
                        app.KQEditField.Value = x1;
                        % hien thi len o ket qua
                end          
%Bai 4.3 (bài số 1,2)
            elseif value2 == 1
                switch app.ChoiseDropDown.Value
                    % Bai so 1
                    case 'Cholesky(b1)'
                        A = [5 3 2 1;3 6 1 2;2 1 5 1;1 2 1 6];
                        B  = [1;4;7;2];
                        % tao mang A,B theo de bai
                        [m,n]=size(A);
                        L=zeros(m,m);
                        row=1; col=1;
                        j=1;
                        for i=1:m
                            a11=sqrt(A(1,1));
                            L(row,col)=a11;
                            if(m~=1)
                                L21=A(j+1:m,1)/a11;
                                L(row+1:end,col)=L21;
                                A=(A(j+1:m,j+1:m)-L21*L21');
                                [m,n]=size(A);
                                row=row+1;
                                col=col+1;
                            end
                        end
                        % L la mang khi duoc phan ly A = L*L'
                        Q = L^(-1)*B;
                        F = L'^(-1)*Q;
                        X = num2str(reshape(F, 1, []));
                        % chuyen doi mang thanh chuoi 
                        app.KQEditField.Value = X;
                        % hien thi len o ket qua
                    % Bai so 2
                    case 'Cholesky(b2)'
                        A = [7 4 3 1;4 8 2 5;3 2 7 4;1 5 4 11];
                        B  = [7;10;9;9];
                        % tao mang A,B theo de bai
                        array = num2str('A = [7 4 3 1;4 8 2 5;3 2 7 4;1 5 4 11] ');
                        app.HamEditField.Value = array;
                        e = num2str('B  = [7;10;9;9]');
                        app.GioiHanEditField.Value = e;
                        % cap nhat mang A,B moi len 

                        [m,n]=size(A);
                        L=zeros(m,m);
                        row=1;col=1;
                        j=1;
                        for i=1:m
                            a11=sqrt(A(1,1));
                            L(row,col)=a11;
                            if(m~=1)
                                L21=A(j+1:m,1)/a11;
                                L(row+1:end,col)=L21;
                                A=(A(j+1:m,j+1:m)-L21*L21');
                                [m,n]=size(A);
                                row=row+1;
                                col=col+1;
                            end
                        end
                        % L la mang khi duoc phan ly A = L*L'
                        Q = L^(-1)*B;
                        F = L'^(-1)*Q;
                        X = num2str(reshape(F', 1, []));
                        % chuyen doi mang thanh chuoi 
                        app.KQEditField.Value = X;
                        % hien thi len o ket qua
                end
% Bai 5.3
            elseif value3 == 1
                    x = [321 322.8 324.2 325];
                    y = [2.50651 2.50893 2.51081 2.51188];
                    x0 = 323.5;
                    % tao mang x,y theo de bai 
                    % x0 la gia tri can tinh toan
                    switch app.ChoiseDropDown.Value
                        %phuong phap Langrange
                        case 'Langrange'
                            dathuc = 0;
                            for i = 1:1:length(x)
                                p = 1;
                                for j = 1:1:length(x)
                                    if j ~= i
                                        p = p*(x0 -x(1,j))/(x(1,i) - x(1,j));
                                    end
                                end
                                h = y(1,i)*p;
                                dathuc = dathuc + h;
                            end
                            b = num2str(dathuc);
                            % chuyen doi tu so sang chuoi
                            app.KQEditField.Value = b;
                            % hien thi len o ket qua
                    end
% Bai 6.7 (bài số 4)
            elseif value4 == 1
                fxy = @(x,y) x^2 + y^2;
                x0 = 0;
                % x0 gia tri dau
                xn = 0.5;
                % xn gia tri cuoi
                y0 = -1;
                % y0 gia tri dau tien
                N = 25;
                % so doan chia
                h = (xn-x0)/N;
                % khoang cach chia
                x = x0:h:xn;
                y(1,1) = y0;
                % gan y(1,1) gia tri dau bang y0
                switch app.ChoiseDropDown.Value
                    % phuong phap Euler
                    case 'Euler'
                        for i = 1:1:N
                            y(1,i+1) = y(1,i) + h*(fxy(x(1,i),y(1,i)));
                        end
                        plot(app.UIAxes,x,y);
                        % ve y theo x hien thi len do thi
                    % phuong phap Euler cai tien
                    case 'Euler cải tiến'
                        for i = 1:1:N
                            y(1,i+1) = y(1,i) + (h/2)*(fxy(x(1,i),y(1,i)));
                            y(1,i+1) = y(1,i) + h*(fxy((x(1 ,i) + h/2), y(1,i+1)));
                        end
                        plot(app.UIAxes,x,y);
                        % ve y theo x hien thi len do thi
                    % phuong phap R-K4
                     case 'R-K4'
                        for i = 1:1:N
                            k1 = h*fxy(x(1,i), y(1,i));
                            k2 = h*fxy(x(1,i) + 0.5*h, y(1,i) + 0.5*k1 );
                            k3 = h*fxy(x(1,i) + 0.5*h, y(1,i) + 0.5*k2 );
                            k4 = h*fxy(x(1,i) + h, y(1,i) + k3 );
                            y(1,i+1) = y(1,i) + (1/6)*(k1 + 2*k2 +2*k3 + k4);
                        end
                        plot(app.UIAxes,x,y);   
                        % ve y theo x hien thi len do thi
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image_logo = uiimage(app.UIFigure);
            app.Image_logo.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Image_avt = uiimage(app.UIFigure);
            app.Image_avt.ImageSource = '1869ccfa5c9290ccc983.jpg';
            app.titleLabel = uilabel(app.UIFigure);
            app.titleLabel.Text = 'BÁO CÁO CUỐI KỲ';
            app.MnhcPhngphptnhvMatlabLabel = uilabel(app.UIFigure);
            app.MnhcPhngphptnhvMatlabLabel.Text = 'Môn học: Phương pháp tính và Matlab';
            app.HvtnNguynNgcNguynLabel = uilabel(app.UIFigure);
            app.HvtnNguynNgcNguynLabel.Text = 'Họ và tên: Nguyễn Ngọc Nguyên';
            app.Lp20DTV1Label = uilabel(app.UIFigure);
            app.Lp20DTV1Label.Text = 'Lớp: 20DTV1';
            app.MSSV20200058Label = uilabel(app.UIFigure);
            app.MSSV20200058Label.Text = 'MSSV: 20200058';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.BT3_2Button = uibutton(app.UIFigure, 'state');
            app.BT3_2Button.ValueChangedFcn = createCallbackFcn(app, @BT3_2ButtonValueChanged, true);
            app.BT3_2Button.Text = 'Bài tập 3.2(bài số 8)';
            app.BT4_3Button = uibutton(app.UIFigure, 'state');
            app.BT4_3Button.ValueChangedFcn = createCallbackFcn(app, @BT4_3ButtonValueChanged, true);
            app.BT4_3Button.Text = 'Bài tập 4.3(bài số 1,2)';
            app.BT5_3Button = uibutton(app.UIFigure, 'state');
            app.BT5_3Button.ValueChangedFcn = createCallbackFcn(app, @BT5_3ButtonValueChanged, true);
            app.BT5_3Button.Text = 'Bài tập 5.3';
            app.BT6_7Button = uibutton(app.UIFigure, 'state');
            app.BT6_7Button.ValueChangedFcn = createCallbackFcn(app, @BT6_7ButtonValueChanged, true);
            app.BT6_7Button.Text = 'Bài tập 6.7(bài số 4)';
            app.PhngphpxlDropDownLabel = uilabel(app.UIFigure);
            app.PhngphpxlDropDownLabel.Text = 'Phương pháp xử lý';
            app.ChoiseDropDown = uidropdown(app.UIFigure);
            app.HmvdliuEditFieldLabel = uilabel(app.UIFigure);
            app.HmvdliuEditFieldLabel.Text = 'Hàm và dữ liệu';
            app.HamEditField = uieditfield(app.UIFigure, 'text');
            app.GIihnEditFieldLabel = uilabel(app.UIFigure);
            app.GIihnEditFieldLabel.Text = 'GIới hạn';
            app.GioiHanEditField = uieditfield(app.UIFigure, 'text');
            app.KtquEditFieldLabel = uilabel(app.UIFigure);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KQEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOAN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end